classdef VCGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        DTab                     matlab.ui.container.Tab
        DplanesLabel             matlab.ui.control.Label
        frontal                  matlab.ui.control.UIAxes
        sagital                  matlab.ui.control.UIAxes
        transversal              matlab.ui.control.UIAxes
        Lamp                     matlab.ui.control.Lamp
        DataloadedLabel          matlab.ui.control.Label
        DTab_2                   matlab.ui.container.Tab
        DplaneLabel              matlab.ui.control.Label
        threedim                 matlab.ui.control.UIAxes
        CurrentplotSpinnerLabel  matlab.ui.control.Label
        CurrentplotSpinner       matlab.ui.control.Spinner
        ViewDropDownLabel        matlab.ui.control.Label
        ViewDropDown             matlab.ui.control.DropDown
        LinetypeDropDownLabel    matlab.ui.control.Label
        LinetypeDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        % public properties
        XML;
        xmlfiles;
        linetype=["ro","r.","r"];
        paxis=[-200 200 -200 200 -200 200];
        qrsaxis=[-1000 1100 -1000 1100 -1000 1100];
        allaxis=[-1000 1100 -1000 1100 -1000 1100];
        VCG_IDT;
        
        %initial values
        On=1;
        Off=600;
        ltindex=1;
        axistype=[-1000 1100 -1000 1100 -1000 1100];
    end
    
    
    methods (Access = public)
        function calcVCG(app)
            
            app.XML=XMLECGParser(app.xmlfiles(app.CurrentplotSpinner.Value).name);
            
            ecg = app.XML.MedianECG.ecg8;
            
            % Construct inverse dower transform matrix
            T = [ 0.156 -0.010 -0.172 -0.074 0.122 0.231 0.239 0.194  ;
                -0.227 0.887 0.057 -0.019 -0.106 -0.022 0.041 0.048  ;
                0.022  0.102 -0.229 -0.310 -0.246 -0.063 0.055 0.108  ]';
            
            % Invers Dower
            app.VCG_IDT = ecg*T;
        end
        
        function plot2d(app)
            plot(app.frontal,app.VCG_IDT(app.On:app.Off,1),app.VCG_IDT(app.On:app.Off,3),app.linetype(app.ltindex)) %XY
            plot(app.sagital,app.VCG_IDT(app.On:app.Off,3),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex)) %YZ
            plot(app.transversal,app.VCG_IDT(app.On:app.Off,1),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex)) %XZ
            
            axis(app.frontal,app.axistype);
            axis(app.sagital,app.axistype);
            axis(app.transversal,app.axistype);
        end
        
        function plot3d(app)
            
            str = 1100;
            plot3(app.threedim,[-str str],[0 0],[0 0],'k',[0 0],[-str str],[0 0],'k',[0 0],[0 0],[-str str],'k'); % plotter et kryds i 3D plottet
            text(app.threedim,str,0,0,'Left');
            text(app.threedim,-str,0,0,'Right');
            text(app.threedim,0,str,0,'Posterior');
            text(app.threedim,0,-str,0,'Anterior');
            text(app.threedim,0,0,str,'Superior');
            text(app.threedim,0,0,-str,'Inferior');
            
            hold(app.threedim,'on');
            
            plot3(app.threedim,app.VCG_IDT(app.On:app.Off,1),app.VCG_IDT(app.On:app.Off,3),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex))
            hold(app.threedim,'off');
            axis(app.threedim,app.axistype);
            axis(app.threedim,'square');        
            %app.threedim.View; 
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            testfiledir='Testdata\';
            app.xmlfiles=dir(fullfile(testfiledir,'*xml'));
            
            app.Lamp.Color=[0.39 0.83 0.07];
            
            app.calcVCG;
            app.plot2d;
            app.plot3d;
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            
            if value=="All"
                app.On=1;
                app.Off=600;
                app.axistype=app.allaxis;
            end
            
            if value=="QRS"
                app.On=app.XML.TestInfo.QOnset;
                app.Off=app.XML.TestInfo.QOffset;
                app.axistype=app.qrsaxis;
            end
            
            if value=="P"
                app.On=app.XML.TestInfo.POnset;
                app.Off=app.XML.TestInfo.POffset;
                app.axistype=app.paxis;
            end
        end

        % Value changed function: CurrentplotSpinner
        function CurrentplotSpinnerValueChanged(app, event)
            value = app.CurrentplotSpinner.Value;
            
            app.calcVCG;
            app.plot2d;
            app.plot3d;
            
            
        end

        % Value changed function: LinetypeDropDown
        function LinetypeDropDownValueChanged(app, event)
            value = app.LinetypeDropDown.Value;
                       
            if value=="o"
                app.ltindex=1;
            end
            
            if value=="."
                app.ltindex=2;
            end
            
            if value=="-"
                app.ltindex=3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '2D';
            app.DplanesLabel = uilabel(app.DTab);
            app.DplanesLabel.Text = '2D planes';
            app.frontal = uiaxes(app.DTab);
            title(app.frontal, 'Frontal')
            xlabel(app.frontal, 'X')
            ylabel(app.frontal, 'Y')
            app.sagital = uiaxes(app.DTab);
            title(app.sagital, 'Sagital')
            xlabel(app.sagital, 'Y')
            ylabel(app.sagital, 'Z')
            app.transversal = uiaxes(app.DTab);
            title(app.transversal, 'Transversal')
            xlabel(app.transversal, 'X')
            ylabel(app.transversal, 'Z')
            app.Lamp = uilamp(app.DTab);
            app.DataloadedLabel = uilabel(app.DTab);
            app.DataloadedLabel.Text = 'Data loaded';
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.DplaneLabel = uilabel(app.DTab_2);
            app.DplaneLabel.Text = '3D plane';
            app.threedim = uiaxes(app.DTab_2);
            title(app.threedim, '3D')
            xlabel(app.threedim, 'X')
            ylabel(app.threedim, 'Y')
            app.CurrentplotSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentplotSpinnerLabel.Text = 'Current plot';
            app.CurrentplotSpinner = uispinner(app.UIFigure);
            app.CurrentplotSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentplotSpinnerValueChanged, true);
            app.CurrentplotSpinner.Value = 1;
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'All', 'QRS', 'P'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'All';
            app.LinetypeDropDownLabel = uilabel(app.UIFigure);
            app.LinetypeDropDownLabel.Text = 'Linetype';
            app.LinetypeDropDown = uidropdown(app.UIFigure);
            app.LinetypeDropDown.Items = {'o', '.', '-'};
            app.LinetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LinetypeDropDownValueChanged, true);
            app.LinetypeDropDown.Value = 'o';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VCGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end